classdef YouBotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StartSensingButton              matlab.ui.control.Button
        StopSensorButton                matlab.ui.control.Button
        UpdateIntervalsecondsEditField  matlab.ui.control.NumericEditField
        UpdateIntervalsecondsEditFieldLabel  matlab.ui.control.Label
        ActionLabel                     matlab.ui.control.Label
        Reconnect                       matlab.ui.control.Button
        EndSimulationButton             matlab.ui.control.Button
        Directions                      matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = public)
        clientID
        sim remApi
        h
        open
        sensorHandle
        LaserTimer timer
    end
    
    methods (Access = private)
        
        function blnSucceed = movementSet(app,forwBackVel, leftRightVel, rotVel)
            %   Move robot forward. Just a test
            if  app.clientID < 0
                disp('Check server connection');
                blnSucceed = false;
                return;
            end

            app.sim.simxSetJointTargetVelocity(app.clientID, app.h(1),-forwBackVel-leftRightVel-rotVel, app.sim.simx_opmode_blocking);
            app.sim.simxSetJointTargetVelocity(app.clientID, app.h(2),-forwBackVel+leftRightVel-rotVel, app.sim.simx_opmode_blocking);
            app.sim.simxSetJointTargetVelocity(app.clientID, app.h(3),-forwBackVel-leftRightVel+rotVel, app.sim.simx_opmode_blocking);
            app.sim.simxSetJointTargetVelocity(app.clientID, app.h(4),-forwBackVel+leftRightVel+rotVel, app.sim.simx_opmode_blocking);
            blnSucceed = true;           
        end

        function blnSucceed = setJointMovement(app,jointIndex, targetTheta)
            %   Move robot forward. Just a test
            if  app.clientID < 0
                disp('Check server connection');
                blnSucceed = false;
                return;
            end
            if jointIndex > 11 || jointIndex < 1 
                disp('Joint Index out of bound');
                blnSucceed = false;
                return;
            end

            app.sim.simxSetJointTargetPosition(app.clientID, app.h(jointIndex),targetTheta, app.sim.simx_opmode_blocking);
            blnSucceed = true;           
        end        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            %inital visibility rules
            app.ActionLabel.Visible = false;
            app.Reconnect.Visible = "off";
            app.Directions.Visible = "off";
            app.EndSimulationButton.Visible = "off";
            app.ActionLabel.Visible = "off";

            app.sim = remApi('remoteApi');
            app.sim.simxFinish(-1);
            app.clientID = app.sim.simxStart('127.0.0.1',19999,true,true,5000,5); 
            app.h = [0,0,0,0,0,0,0,0,0,0,0]; % 11 total joints
            app.open = true;
            if (app.clientID>-1)
                %could do something here. Not sure what i would do
                app = GetHandles(app);
                %success visibile rules
                app.ActionLabel.Visible = false;
                app.Reconnect.Visible = "off";
                app.Directions.Visible = "on";
                app.EndSimulationButton.Visible = "on";
                app.ActionLabel.Visible = "on";
                slamGui();

            else
                app.Reconnect.Visible = "on";                
                disp('Failed connecting to remote API server');
            end            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            if app.clientID > -1
                key = event.Key;
                value = "nil";
                switch key
                    case {'leftarrow',"A"}
                        movementSet(app,0,0,-0.5);
                        value = "Rotating counter-clockwise";
                        disp('Left arrow key pressed');

                    case {'rightarrow', "D"}
                        movementSet(app,0,0,0.5);
                        value = "Rotating clockwise";
                        disp('Right arrow key pressed');

                    case {'uparrow', "W"}
                        movementSet(app,0.5,0,0);
                        value = "Moving Forward";
                        disp('Up arrow key pressed');

                    case {'downarrow', "S"}
                        movementSet(app,-0.5,0,0);
                        value = "Moving Backward";
                        disp('Down arrow key pressed');

                    case {'space',"X"}
                        movementSet(app,0,0,0);
                        value = "Staying in place";
                        disp('Spacebar Pressed');
                    otherwise

                        % Code for other keys
                        disp(['Key pressed: ', key]);
                        value = strcat("Unknown Key: ",key);
                end
                app.ActionLabel.Text = value;
                app.ActionLabel.Visible = true;
            end
         
        end

        % Button pushed function: EndSimulationButton
        function EndSimulationButtonPushed(app, event)
            %end the simulation
            if app.clientID > -1
                app.sim.simxFinish(app.clientID);
                close all force;
            end

        end

        % Button pushed function: Reconnect
        function ReconnectButtonPushed(app, event)
            %attempt reconnect by calling startFcn            
            startupFcn(app);
            if app.clientID < 0
                app.ActionLabel.Visible = "on";
                app.ActionLabel.Text = "Reconnect Failed";
            else
                app.ActionLabel.Text = "";
            end
        end

        % Value changed function: UpdateIntervalsecondsEditField
        function UpdateIntervalsecondsEditFieldValueChanged(app, event)
            value = app.UpdateIntervalsecondsEditField.Value;          
        end

        % Button pushed function: StopSensorButton
        function StopSensorButtonPushed(app, event)
            stop(app.LaserTimer);
            delete(app.LaserTimer);
        end

        % Button pushed function: StartSensingButton
        function StartSensingButtonPushed(app, event)
        
            app.LaserTimer = timer;
            app.LaserTimer.Period = app.UpdateIntervalsecondsEditField.Value; 
            app.LaserTimer.ExecutionMode = 'fixedRate';
            app.LaserTimer.TimerFcn = @(~,~) plotLaserScanner(app);
            start(app.LaserTimer);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plot 1')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Plot 2')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.Directions = uilabel(app.UIFigure);
            app.Directions.Text = {'Left Arrow - Rotate Left'; 'Right Arrow - Rotate Right'; 'Up Arrow - Forward'; 'Down Arrow - Backward'; 'Spacebar - Stop in place'};
            app.EndSimulationButton = uibutton(app.UIFigure, 'push');
            app.EndSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @EndSimulationButtonPushed, true);
            app.EndSimulationButton.Text = 'End Simulation';
            app.Reconnect = uibutton(app.UIFigure, 'push');
            app.Reconnect.ButtonPushedFcn = createCallbackFcn(app, @ReconnectButtonPushed, true);
            app.Reconnect.Visible = 'off';
            app.Reconnect.Text = 'Attempt Reconnect';
            app.ActionLabel = uilabel(app.UIFigure);
            app.ActionLabel.Text = '';
            app.UpdateIntervalsecondsEditFieldLabel = uilabel(app.UIFigure);
            app.UpdateIntervalsecondsEditFieldLabel.Text = 'Update Interval (seconds)';
            app.UpdateIntervalsecondsEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpdateIntervalsecondsEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateIntervalsecondsEditFieldValueChanged, true);
            app.UpdateIntervalsecondsEditField.Value = 0.5;
            app.StopSensorButton = uibutton(app.UIFigure, 'push');
            app.StopSensorButton.ButtonPushedFcn = createCallbackFcn(app, @StopSensorButtonPushed, true);
            app.StopSensorButton.Text = 'Stop Sensor';
            app.StartSensingButton = uibutton(app.UIFigure, 'push');
            app.StartSensingButton.ButtonPushedFcn = createCallbackFcn(app, @StartSensingButtonPushed, true);
            app.StartSensingButton.Text = 'Start Sensing';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = YouBotApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end